classdef GUI_run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TourIntoThePictureComputerVisionSS24Group32UIFigure  matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        TOURINTOTHEPICTURELabel       matlab.ui.control.Label
        EXPERTMODEPanel               matlab.ui.container.Panel
        Switch                        matlab.ui.control.RockerSwitch
        GridLayout3                   matlab.ui.container.GridLayout
        PlanarAreaShapesButtonGroup   matlab.ui.container.ButtonGroup
        QuadrangleButton              matlab.ui.control.RadioButton
        RectangleButton               matlab.ui.control.RadioButton
        ResolutionPanel               matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        ForegroundObjectsButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton                    matlab.ui.control.ToggleButton
        ResetallButton                matlab.ui.control.ToggleButton
        ResetcurrentButton            matlab.ui.control.ToggleButton
        DrawButton                    matlab.ui.control.ToggleButton
        REQUIREDPanel                 matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        DrawVanishingPointButton      matlab.ui.control.Button
        DrawPlanarAreaButton          matlab.ui.control.Button
        CONTROLPanel                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RUNButton                     matlab.ui.control.Button
        SelectImageButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        HOMETab                       matlab.ui.container.Tab
        GridLayout17                  matlab.ui.container.GridLayout
        SelectedDataLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        HELPTab                       matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        MainPanelInHelp               matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        Panel_3                       matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        TabGroup_2                    matlab.ui.container.TabGroup
        InfoTab                       matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        Label_4                       matlab.ui.control.Label
        InfoHeaderLabel_8             matlab.ui.control.Label
        PlanarAreaTab                 matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        RectGifButton_3               matlab.ui.control.Button
        RectGifButton_2               matlab.ui.control.Button
        RectExp                       matlab.ui.control.Label
        InfoHeaderLabel_2             matlab.ui.control.Label
        VanishingPointTab             matlab.ui.container.Tab
        GridLayout11                  matlab.ui.container.GridLayout
        FPGifButton                   matlab.ui.control.Button
        FPExp                         matlab.ui.control.Label
        InfoHeaderLabel_9             matlab.ui.control.Label
        ForegroundObjectsTab          matlab.ui.container.Tab
        GridLayout12                  matlab.ui.container.GridLayout
        GridLayout13                  matlab.ui.container.GridLayout
        FGobjGifButton_2              matlab.ui.control.Button
        FGobjGifButton                matlab.ui.control.Button
        FGobjExp                      matlab.ui.control.Label
        InfoHeaderLabel_10            matlab.ui.control.Label
        ResolutionTab                 matlab.ui.container.Tab
        GridLayout14                  matlab.ui.container.GridLayout
        ResolutionGifButton           matlab.ui.control.Button
        ResolutionExp                 matlab.ui.control.Label
        InfoHeaderLabel_11            matlab.ui.control.Label
        SimulationTab                 matlab.ui.container.Tab
        GridLayout15                  matlab.ui.container.GridLayout
        SimButton                     matlab.ui.control.Button
        SimExp                        matlab.ui.control.Label
        InfoHeaderLabel_12            matlab.ui.control.Label
        TroubleshootingTab            matlab.ui.container.Tab
        GridLayout18                  matlab.ui.container.GridLayout
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        InfoHeaderLabel_7             matlab.ui.control.Label
        UIAxesMac                     matlab.ui.control.UIAxes
        UIAxesOutdoorImg              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        help_id % id to parameterize the current help tab number
        rectangleobj    % ROI object for rectangle
        FPobj           % ROI object for vanishing point
        fg_num = 0      % number of foreground objects
        imgplot         % handle for plot of img
        set_pos         % if set position when creating rectangle/FP (for init rect/FP)
        curr_rect_type = 'images.roi.Rectangle'  % if rectangle is a polygon or rectangle
        abortVar = 0
    end
    
    properties (Access = public)
        filename            % filename (REQUIRED FOR MINIGAME)
        img                 % input image in uint8
        imgd                % input image in double
        P1                  % left top point coords of rectangle
        P2                  % right top point
        P3                  % right bottom point coords of rectangle
        P4                  % left bottom point
        FP                  % vanishing point coords
        lineplot            % radial lines through FP and IR edges
        fgobj = {}          % ROI object for foreground polygons
        masks = {}          % contains the masks of the fg obj (logical)
        maxfgarea = 25e3;   % maximum area of polygon to use inpaintExemplar
        scaling_fact        % factor for scaling
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if ~exist("controls/", 'dir')   % add paths if GUI is started directly
                addpath("Functions");
                addpath("Functions/controls");
                addpath("CV-Challenge-24-Datensatz");
            end

            % read in default image
            imgFile = "oil-painting.png";
            if exist(imgFile, 'file')
                app.filename = imgFile;
                app.img = imread(imgFile);
                app.SelectedDataLabel.Text = strcat(app.SelectedDataLabel.Text, " ", imgFile);
                app.imgd = double(app.img);
                app.UIAxes.XLim = [0,size(app.imgd,2)];
                app.UIAxes.YLim = [0,size(app.imgd,1)];
                app.imgplot = imagesc(app.UIAxes, app.img);
            end

            % Hide expert mode
            ResolutionValueChanged(app, []);

            % define initial rectangle and vanishing point
            app.set_pos = true;
            RectangleButtonPushed(app,[]);
            VanishingPointButtonPushed(app,[]);
            app.set_pos = false;

            disableDefaultInteractivity(app.UIAxes);    % make sample points unclickable
            app.UIAxes.Toolbar.Visible = 'off';         % hide Toolbar
            
            % Question Dialog to help user when GUI is opened
            % pause(1)    
            % tut_quest = "It looks like you're new here. How about a quick introduction tutorial?";
            % run_tut = questdlg(tut_quest,"Startup Tutorial","Okay","Skip","Skip");
            % switch run_tut
            %     case "Okay"
            %         figure(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            %         focus(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            %         app.help_id = 1;
            %         app.GUI_help_window = GUI_help(app, app.help_id); % open help window
            %     case "Skip"
            %         figure(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            %         focus(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            % end

            % change title color of help images in troubleshooting tab,
            % because they cannot be set in the menu
            app.UIAxesMac.XLabel.Color = [0.80,0.80,0.80];
            app.UIAxesOutdoorImg.XLabel.Color = [0.80,0.80,0.80];
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [app.filename,pathname] = uigetfile('*.jpg;*.png;*.jpeg;*.bmp', 'Select an image');
            fullfilename = fullfile(pathname,app.filename);
            if app.filename ~= 0    % read in new image if changed
                delete(app.imgplot);
                app.img = imread(fullfilename);
            else
                app.img = uint8(app.imgd); % if no new file selected, keep old image
            end
            app.imgd = double(app.img );
            
            if ~isempty(app.img)    
                cla(app.UIAxes);   % clear figure

                % Show selected image name in selected data label
                if app.filename ~=0
                    app.SelectedDataLabel.Text = strcat("Selected Data:", " ", app.filename);
                end

                app.UIAxes.XLim = [0,size(app.imgd,2)];
                app.UIAxes.YLim = [0,size(app.imgd,1)];
                
                app.imgplot = imagesc(app.UIAxes, app.img);

                % set rect and FP anew
                app.set_pos = true;
                app.DrawPlanarAreaButton.Text{1,:} = 'Draw';
                RectangleButtonPushed(app,[]);
                app.DrawVanishingPointButton.Text{1,:} = 'Draw';
                VanishingPointButtonPushed(app,[]);
                app.set_pos = false;
                
                dummyevent.NewValue.Text = "Reset all";
                ForegroundObjectButtonPushed(app, dummyevent);
                app.masks = {};     % clear all masks
                app.fgobj = {};
                app.fg_num = 0;

                % put UIFigure in Focus again
                focus(app.TourIntoThePictureComputerVisionSS24Group32UIFigure)
            end
        end

        % Button pushed function: DrawPlanarAreaButton
        function RectangleButtonPushed(app, event)
            
            if isempty(app.imgd) && ~app.set_pos
                errordlg('Please load an image first.', 'Error');
                return;
            end

            if isequal(app.DrawPlanarAreaButton.Text(1,:), "Reset") && ~isempty(app.rectangleobj) && isequal(app.curr_rect_type, class(app.rectangleobj)) % If Reset button pressed
                app.rectangleobj.delete;             % delete the rectangle
                app.rectangleobj = [];
                if ~isempty(app.lineplot), delete(app.lineplot); end    % delete lines
                app.P1 = [];  app.P2 = []; app.P3 = []; app.P4 = [];    % reset rectangle coords
                app.DrawPlanarAreaButton.Text{1,:} = 'Draw';     % rename button
                if isequal(app.curr_rect_type, 'images.roi.Polygon')
                    % DrawRectPolySelectionChanged(app, []);        % plot default quadrangle
                    % delete quadrangle and switch to rectangle
                    app.RectangleButton.Value = true;
                    app.curr_rect_type = 'images.roi.Rectangle';
                end
            
                app.RUNButton.BackgroundColor = [1.00,0.33,0.33];   % make run button red
                
            else
                app.DrawPlanarAreaButton.Text{1,:} = 'Reset';     % change name to reset if rectangle button pressed
                
                if ~isequal(app.TabGroup.SelectedTab, app.TabGroup.Children(1))
                    app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                end

                if app.set_pos && ~isempty(app.rectangleobj)
                    app.rectangleobj.delete;             % delete the rectangle
                    app.rectangleobj = [];
                end
                DrawRectPolySelectionChanged(app, []);  % run drawing rectangle
                if isempty(app.rectangleobj.Position)    % if aborted with 'Esc'
                    app.rectangleobj.delete;
                    app.rectangleobj = [];
                    app.DrawPlanarAreaButton.Text{1,:} = 'Draw';
                    return
                end

                
                % add listeners to update coords when rectangle moved
                addlistener(app.rectangleobj,'MovingROI',@(src, event)allevents(src,event,app));

                % update positions for rectangle/quadrangle
                if isequal(app.curr_rect_type, 'images.roi.Rectangle')
                    app.P1 = [app.rectangleobj.Position(1), app.rectangleobj.Position(2)];
                    app.P2 = [app.rectangleobj.Position(1)+app.rectangleobj.Position(3), app.rectangleobj.Position(2)];
                    app.P3 = [app.rectangleobj.Position(1)+app.rectangleobj.Position(3), app.rectangleobj.Position(2)+app.rectangleobj.Position(4)];
                    app.P4 = [app.rectangleobj.Position(1), app.rectangleobj.Position(2)+app.rectangleobj.Position(4)];
                else
                    app.P1 = app.rectangleobj.Position(1,:);
                    app.P2 = app.rectangleobj.Position(2,:);
                    app.P3 = app.rectangleobj.Position(3,:);
                    app.P4 = app.rectangleobj.Position(4,:);
                end
                
                if ~isempty(app.FP)
                    app.RUNButton.BackgroundColor = [0.33,0.62,0.18];   % make run button green
                end
            end
            
        end

        % Button pushed function: DrawVanishingPointButton
        function VanishingPointButtonPushed(app, event)

            if isempty(app.imgd)
                errordlg('Please load an image first.', 'Error');
                return;
            end

            if isequal(app.DrawVanishingPointButton.Text(1,:), "Reset") && ~isempty(app.FPobj)
                app.FPobj.delete
                if ~isempty(app.lineplot), delete(app.lineplot); end
                app.FP = [];
                app.DrawVanishingPointButton.Text{1,:} = 'Draw';

                app.RUNButton.BackgroundColor = [1.00,0.33,0.33];
            else
                app.DrawVanishingPointButton.Text{1,:} = 'Reset';

                if ~isequal(app.TabGroup.SelectedTab, app.TabGroup.Children(1))
                    app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                end

                if app.set_pos 
                    hold(app.UIAxes, 'on' )
                    app.FPobj = drawpoint(app.UIAxes, 'Position', [size(app.imgd,2)/2,size(app.imgd,1)/2], 'MarkerSize', 6, 'LineWidth', 3, 'Color', 'g');
                    hold(app.UIAxes, 'off' )
                else
                    app.FPobj = drawpoint(app.UIAxes, 'MarkerSize', 6, 'LineWidth', 3, 'Color', 'g');% 'EdgeAlpha', 0);
                    if isempty(app.FPobj.Position)    % if aborted with 'Esc'
                        app.FPobj.delete;
                        app.FPobj = [];
                        app.DrawVanishingPointButton.Text{1,:} = 'Draw';
                        return
                    end
                end
                addlistener(app.FPobj,'MovingROI',@(src, event)allevents(src,event,app));
                app.FP = app.FPobj.Position;
                draw_lines(app);    % draw lines
                
                if ~isempty(app.P1)
                    app.RUNButton.BackgroundColor = [0.33,0.62,0.18];
                end
            end

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            % display error when no image or FP/Rect is selected
            if isempty(app.imgd)
                errordlg('Please load an image first.', 'Error');
                return;
            end
            if isempty(app.FP) || isempty(app.P1)
                errordlg('Please define vanishing point and rectangle first.', 'Error');
                return;
            end
            
            run_tour(app);  % launch simulation

            uiwait(gcf);    % block the gui until simulation window closed
            focus(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);    % set gui in focus again
            
        end

        % Selection changed function: ForegroundObjectsButtonGroup
        function ForegroundObjectButtonPushed(app, event)
            if event.NewValue.Text == "Reset current"
                % delete the currently drawn polygons

                app.ForegroundObjectsButtonGroup.SelectedObject = app.NoneButton;   % select dummy button to make buttons clickable again

                % to abort draw polygon when not finished drawing
                h_roi = findobj(app.TourIntoThePictureComputerVisionSS24Group32UIFigure, 'Tag', 'MyDrawPolygon'); 
                if ~isempty(h_roi)
                    h_roi.delete(); % Delete the ROI object. 
                elseif ~isempty(app.fgobj)      % delete when finished drawing
                    delete(app.fgobj{app.fg_num}); 
                    app.fgobj(end) = [];
                    app.fg_num = numel(app.fgobj);
                end

                uiresume(app.TourIntoThePictureComputerVisionSS24Group32UIFigure) % Continue execution of program

            elseif event.NewValue.Text == "Reset all"   % delete all polygons

                app.ForegroundObjectsButtonGroup.SelectedObject = app.NoneButton;   % select dummy button to make buttons clickable again
                
                % abort when roi not finished drawing
                h_roi = findobj(app.TourIntoThePictureComputerVisionSS24Group32UIFigure, 'Tag', 'MyDrawPolygon'); % Find the polygon currently drawn.
                if ~isempty(h_roi)
                    h_roi.delete(); % Delete the ROI object.
                end

                % deletes all foreground objects
                if ~isempty(app.fgobj)
                    cellfun(@delete,app.fgobj); 
                end     % delete all fgobj ROIs

                app.fgobj = {};
                app.masks = {};
                app.fg_num = 0;

                uiresume(app.TourIntoThePictureComputerVisionSS24Group32UIFigure)

            else    % draw polygon
                app.ForegroundObjectsButtonGroup.SelectedObject = app.NoneButton;
                if ~isequal(app.TabGroup.SelectedTab, app.TabGroup.Children(1))
                    app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                end

                if app.fg_num > 1 && isempty(app.fgobj{app.fg_num}.Position)
                    app.fgobj(app.fg_num) = [];
                end

                % add a tag to the object to find it when user resets without finishing drawing
                app.fgobj{app.fg_num+1} = drawpolygon(app.UIAxes, 'Tag', 'MyDrawPolygon', 'LineWidth', 1, 'MarkerSize', 5, 'Color', [0, 1, 1]);% 'EdgeAlpha', 0);

                if  ~isempty(app.fgobj{app.fg_num+1}) % only store if field has not been emptied
                    if isvalid(app.fgobj{app.fg_num+1}) % test if roi has been deleted
                        app.fgobj{app.fg_num+1}.Tag = '';   % clear tag if polygon successfully drawn
                        app.UIAxes.Children = app.UIAxes.Children([2:end-1, 1, end]);   % to not block FP/IR edges
                    end
                else
                    app.fgobj(app.fg_num+1) = [];       % if not successfully drawn (e.g. aborted with Esc or not finished)
                end

                % remove all empty or deleted entries
                app.fgobj = app.fgobj(~cellfun('isempty', app.fgobj));
                app.fgobj = app.fgobj(cellfun(@isvalid, app.fgobj));
                app.fg_num = numel(app.fgobj); % update fg_num
                
            end
        end

        % Button down function: PlanarAreaShapesButtonGroup
        function DrawRectPolySelectionChanged(app, event)
            % Function to switch rectangle with quadrangle.
            % If no rect/poly created, it creates one.
            
            selectedButton = app.PlanarAreaShapesButtonGroup.SelectedObject;
            if selectedButton.Text == "Rectangle"   % draw the rectangle at the last position of P1 and P3
                if ~isempty(app.rectangleobj)
                    app.rectangleobj.delete;
                    app.rectangleobj = [];
                    app.rectangleobj = drawrectangle(app.UIAxes, 'Position', [app.P1(1),app.P1(2),app.P3(1) - app.P1(1),app.P3(2) - app.P1(2)], ...
                        'FaceSelectable', false, 'FaceAlpha', 0, 'LineWidth', 1, 'Color', 'g');
                elseif isempty(app.rectangleobj) && app.set_pos    % if no rectangle obj is there (e.g. has been reset)
                    app.rectangleobj = drawrectangle(app.UIAxes, 'Position', [size(app.imgd,2)/4,size(app.imgd,1)/4,size(app.imgd,2)/2,size(app.imgd,1)/2], ...
                            'FaceSelectable', false, 'FaceAlpha', 0, 'LineWidth', 1, 'Color', 'g');
                else
                    app.rectangleobj = drawrectangle(app.UIAxes, 'FaceSelectable', false, 'FaceAlpha', 0, 'LineWidth', 1, 'Color', 'g'); % draw ROI rectangle
                end
                % update points for draw_lines
                app.P1 = [app.rectangleobj.Position(1), app.rectangleobj.Position(2)];
                app.P2 = [app.rectangleobj.Position(1)+app.rectangleobj.Position(3), app.rectangleobj.Position(2)];
                app.P3 = [app.rectangleobj.Position(1)+app.rectangleobj.Position(3), app.rectangleobj.Position(2)+app.rectangleobj.Position(4)];
                app.P4 = [app.rectangleobj.Position(1), app.rectangleobj.Position(2)+app.rectangleobj.Position(4)];
            
            else    % quadrangle
                if ~isempty(app.rectangleobj)     % use coords from rectangle
                    app.rectangleobj.delete;
                    app.rectangleobj = [];
                    app.rectangleobj = drawpolygon(app.UIAxes, 'Position', [app.P1;app.P2;app.P3;app.P4], ...
                        'FaceSelectable', false, 'FaceAlpha', 0, 'LineWidth', 1, 'Color', 'g');
                else    % create default size quadrangle if no rectangle; can't draw freely bc only 4 edges allowed!
                    app.rectangleobj = drawpolygon(app.UIAxes, 'Position', ...
                        [size(app.imgd,2)/4,size(app.imgd,1)/4; size(app.imgd,2)*3/4,size(app.imgd,1)/4; size(app.imgd,2)*3/4,size(app.imgd,1)*3/4; size(app.imgd,2)/4,size(app.imgd,1)*3/4], ...
                        'FaceSelectable', false, 'FaceAlpha', 0, 'LineWidth', 1, 'Color', 'g');
                end
                % update positions
                app.P1 = app.rectangleobj.Position(1,:);
                app.P2 = app.rectangleobj.Position(2,:);
                app.P3 = app.rectangleobj.Position(3,:);
                app.P4 = app.rectangleobj.Position(4,:);
            end

            draw_lines(app);    % draw radial lines

        end

        % Selection changed function: PlanarAreaShapesButtonGroup
        function PlanarAreaShapesButtonGroupSelectionChanged(app, event)
            % called when selection of rect/poly changed.
            % Stores the class the new rectangleobj should have.

            selectedButton = app.PlanarAreaShapesButtonGroup.SelectedObject;
            if isequal(selectedButton.Text, 'Rectangle')
                app.curr_rect_type = 'images.roi.Rectangle';
            else
                app.curr_rect_type = 'images.roi.Polygon';
            end
            RectangleButtonPushed(app, []); % call so that rectangle will be created
        end

        % Close request function: 
        % TourIntoThePictureComputerVisionSS24Group32UIFigure
        function TourIntoThePictureComputerVisionSS24Group32UIFigureCloseRequest(app, event)
            % for what to do when closing GUI
            delete(app)
            
        end

        % Button down function: HELPTab
        function HelpButtonPushed(app, event)
            % always put selection to info tab when switched to help tab
            app.help_id = 1;
            app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(1);
 
        end

        % Callback function: ForegroundObjectsTab, InfoTab,
        % MainPanelInHelp, 
        % ...and 8 other components
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup_2.SelectedTab;

            % show corresponding gif if switched to another tab
            switch selectedTab.Title
                case 'Info'
                    app.help_id = 1;
                case 'Planar Area'
                    app.help_id = 2;
                    app.RectGifButton_2.Icon = "Help/RectEasyGif.gif";
                    app.RectGifButton_3.Icon = "Help/RectExpertGif.gif";
                case 'Vanishing Point'
                    app.help_id = 3;
                    app.FPGifButton.Icon = "Help/FPEasyGif.gif";
                case 'Foreground Objects'
                    app.help_id = 4;
                    app.FGobjGifButton.Icon = "Help/FGobjDrawGif.gif";
                    app.FGobjGifButton_2.Icon = "Help/FGobjResetGif.gif";
                case 'Resolution'
                    app.help_id = 5;
                    app.ResolutionGifButton.Icon = "Help/ResolutionGif.gif";
                case 'Simulation'
                    app.help_id = 6;
                    app.SimButton.Icon = "Help/SimGif.gif";
                case 'Troubleshooting'
                    app.help_id = 7;  
                    imagesc(app.UIAxesMac, uint8(imread("Help/MacOSSettings.png")));
                    disableDefaultInteractivity(app.UIAxesMac);    % make sample points unclickable
                    app.UIAxesMac.Toolbar.Visible = 'off';         % hide Toolbar
                    imagesc(app.UIAxesOutdoorImg, imread("Help/OutdoorImages.jpg"));
                    disableDefaultInteractivity(app.UIAxesOutdoorImg);    % make sample points unclickable
                    app.UIAxesOutdoorImg.Toolbar.Visible = 'off';         % hide Toolbar
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % change selected Tab to neighbor on the left
            if app.help_id > 1
                app.help_id = app.help_id - 1;
            else    % wrap around to last tab
                app.help_id = length(app.TabGroup_2.Children);
            end
            app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(app.help_id);
            TabGroupSelectionChanged(app,[]);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % change selected Tab to the neighbor on the right
            if app.help_id < length(app.TabGroup_2.Children)
                app.help_id = app.help_id + 1;
            else    % wrap around to first tab
                app.help_id = 1;
            end
            app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(app.help_id);
            TabGroupSelectionChanged(app,[]);
        end

        % Value changed function: Switch
        function SwitchChangedFcn(app, event)
            value = app.Switch.Value;
            % to hide/show expert mode

            if value == "  "     % on
                app.Panel_2.Visible = "Off";    
            elseif value == " "    % off
                app.Panel_2.Visible = "On";     % show a panel above settings to hide them

                % Reset all changes when expert mode is switched off
                % Reset slider
                app.Slider.Value = 50;
                app.scaling_fact = app.Slider.Value*100;
                % reset foreground objects
                dummyevent.NewValue.Text = "Reset all";
                ForegroundObjectButtonPushed(app, dummyevent);
                app.masks = {};     % clear all masks
                app.fgobj = {};
                app.fg_num = 0;
                % reset quadrangle to rectangle
                if isequal(app.PlanarAreaShapesButtonGroup.SelectedObject.Text, 'Quadrangle')
                    app.PlanarAreaShapesButtonGroup.SelectedObject = app.RectangleButton;
                    PlanarAreaShapesButtonGroupSelectionChanged(app, []);
                end
            end
        end

        % Value changed function: Slider
        function ResolutionValueChanged(app, event)
            value = app.Slider.Value;
            app.scaling_fact = value*100; % scale factor to match the real threshold range
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TourIntoThePictureComputerVisionSS24Group32UIFigure = uifigure('Visible', 'off');
            app.TourIntoThePictureComputerVisionSS24Group32UIFigure.Name = 'Tour Into The Picture - Computer Vision SS24 Group 32';
            app.TourIntoThePictureComputerVisionSS24Group32UIFigure.CloseRequestFcn = createCallbackFcn(app, @TourIntoThePictureComputerVisionSS24Group32UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.GridLayout17 = uigridlayout(app.HOMETab);
            app.UIAxes = uiaxes(app.GridLayout17);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectedDataLabel = uilabel(app.GridLayout17);
            app.SelectedDataLabel.Text = 'Selected Data: ';
            app.HELPTab = uitab(app.TabGroup);
            app.HELPTab.Tooltip = {''};
            app.HELPTab.Title = 'HELP';
            app.HELPTab.ButtonDownFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.GridLayout5 = uigridlayout(app.HELPTab);
            app.MainPanelInHelp = uipanel(app.GridLayout5);
            app.MainPanelInHelp.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout6 = uigridlayout(app.MainPanelInHelp);
            app.TabGroup_2 = uitabgroup(app.GridLayout6);
            app.TabGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup_2.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InfoTab = uitab(app.TabGroup_2);
            app.InfoTab.Title = 'Info';
            app.InfoTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout8 = uigridlayout(app.InfoTab);
            app.InfoHeaderLabel_8 = uilabel(app.GridLayout8);
            app.InfoHeaderLabel_8.Text = ' Tour into the Picture - Computer Vision SS24 - Group 32';
            app.Label_4 = uilabel(app.GridLayout8);
            app.Label_4.Text = {'Welcome to our project!'; ''; 'With this application, you can create a 3D tour into a picture.'; 'Just choose your desired image, define a planar area (e.g. the back wall of a room) and the vanishing point. '; 'You will be able to walk around in your created room!'; 'Click through the help pages to get brief instructions on how to use the GUI.'; ''; 'The most important steps:'; '- Choose your desired image with ''Select image''.'; '- In the GUI the rectangle and vanishing point are already loaded. '; '  You can drag them to the desired positions.'; '- Click on ''Run'' to start the 3D model.'; '- IMPORTANT for MacOS users:'; '  When launching the simulation for the first time, a window will popup that asks you to enable controls for '; '  matlab in the settings. Please allow these changes, otherwise the camera control will not work!'; '- In the 3D model, move the cursor to look around. Controls for moving around are explained at the bottom left. '; '  Press ''Esc'' on your keyboard to leave the simulation. '; '  Now you can adjust your settings in the GUI window and start the model again.'; ''; 'Have so much fun!'};
            app.PlanarAreaTab = uitab(app.TabGroup_2);
            app.PlanarAreaTab.Title = 'Planar Area';
            app.PlanarAreaTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout9 = uigridlayout(app.PlanarAreaTab);
            app.InfoHeaderLabel_2 = uilabel(app.GridLayout9);
            app.InfoHeaderLabel_2.Text = 'Planar Area';
            app.RectExp = uilabel(app.GridLayout9);
            app.RectExp.Text = {'Specify the rectangle that contours the reference plane in the picture.'; 'Click on Reset to reset the shape, click on Draw to re-draw it by drag&drop.'; ''; 'Expert Mode:'; 'Change the shape to a quadrangle in order to control the edges freely.'};
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.RectGifButton_2 = uibutton(app.GridLayout10, 'push');
            app.RectGifButton_2.Text = '';
            app.RectGifButton_3 = uibutton(app.GridLayout10, 'push');
            app.RectGifButton_3.Text = '';
            app.VanishingPointTab = uitab(app.TabGroup_2);
            app.VanishingPointTab.Title = 'Vanishing Point';
            app.VanishingPointTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout11 = uigridlayout(app.VanishingPointTab);
            app.InfoHeaderLabel_9 = uilabel(app.GridLayout11);
            app.InfoHeaderLabel_9.Text = 'Vanishing Point';
            app.FPExp = uilabel(app.GridLayout11);
            app.FPExp.Text = {'Specify the vanishing point of your image. You can drag it around, or reset it and place it a new. '; 'The Vanishing Point should be inside the planar area for proper results.'};
            app.FPGifButton = uibutton(app.GridLayout11, 'push');
            app.FPGifButton.Text = '';
            app.ForegroundObjectsTab = uitab(app.TabGroup_2);
            app.ForegroundObjectsTab.Title = 'Foreground Objects';
            app.ForegroundObjectsTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout12 = uigridlayout(app.ForegroundObjectsTab);
            app.InfoHeaderLabel_10 = uilabel(app.GridLayout12);
            app.InfoHeaderLabel_10.Text = 'Foreground Objects';
            app.FGobjExp = uilabel(app.GridLayout12);
            app.FGobjExp.Text = {'Mark object as foreground to remove them from the image and place them into the 3D scene.'; 'Click Draw to draw a new polygon.'; 'Please remember to connect your last dot with the first dot in order to add the foreground object. '; 'Select Reset current to reset the most recently drawn polygon. '; 'Press Reset all to remove all polygons from the image.'};
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.FGobjGifButton = uibutton(app.GridLayout13, 'push');
            app.FGobjGifButton.Text = '';
            app.FGobjGifButton_2 = uibutton(app.GridLayout13, 'push');
            app.FGobjGifButton_2.Text = '';
            app.ResolutionTab = uitab(app.TabGroup_2);
            app.ResolutionTab.Title = 'Resolution';
            app.ResolutionTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout14 = uigridlayout(app.ResolutionTab);
            app.InfoHeaderLabel_11 = uilabel(app.GridLayout14);
            app.InfoHeaderLabel_11.Text = 'Resolution / Scaling of the 3D model';
            app.ResolutionExp = uilabel(app.GridLayout14);
            app.ResolutionExp.Text = {'You can reduce the resolution of the 3D scene by lowering the value on the resolution slider.'; 'For low-end devices, high resolutions may take longer to render.'; 'Caution: If your device has low RAM capacity, a high resolution value can crash matlab or even your device.'};
            app.ResolutionGifButton = uibutton(app.GridLayout14, 'push');
            app.ResolutionGifButton.Text = '';
            app.SimulationTab = uitab(app.TabGroup_2);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout15 = uigridlayout(app.SimulationTab);
            app.InfoHeaderLabel_12 = uilabel(app.GridLayout15);
            app.InfoHeaderLabel_12.Text = 'Simulation / 3D Model';
            app.SimExp = uilabel(app.GridLayout15);
            app.SimExp.Text = {'Start the simulation by clicking Run.'; 'A new window with the modeled 3D scene will appear and the mouse will be locked to the center of the window.'; 'You can look around using the mouse, move with WASD, fly with SPACE (Up) or C (down), '; 'and scroll with mouse wheel/two fingers.'; 'To close the simulation, press ESCAPE.'};
            app.SimButton = uibutton(app.GridLayout15, 'push');
            app.SimButton.Text = '';
            app.TroubleshootingTab = uitab(app.TabGroup_2);
            app.TroubleshootingTab.Title = 'Troubleshooting';
            app.TroubleshootingTab.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout18 = uigridlayout(app.TroubleshootingTab);
            app.UIAxesOutdoorImg = uiaxes(app.GridLayout18);
            xlabel(app.UIAxesOutdoorImg, 'Outdoor Images')
            app.UIAxesOutdoorImg.XTick = [];
            app.UIAxesOutdoorImg.YTick = [];
            app.UIAxesMac = uiaxes(app.GridLayout18);
            xlabel(app.UIAxesMac, 'MacOS Settings')
            app.UIAxesMac.XTick = [];
            app.UIAxesMac.YTick = [];
            app.InfoHeaderLabel_7 = uilabel(app.GridLayout18);
            app.InfoHeaderLabel_7.Text = 'Troubleshooting / Software Specifics / Tips';
            app.Label = uilabel(app.GridLayout18);
            app.Label.Text = {'For issues with low-graphics on Linux (e.g. EIkon PCs):'; '- launch MATLAB from the console and add a flag to enable OpenGL by typing "matlab -softwareopengl".'; '- the 3D scene will now work, but be prepared for it to be much slower than usual.'};
            app.Label_2 = uilabel(app.GridLayout18);
            app.Label_2.Text = {'Getting better results with outdoor pictures:'; '- align the lower line to the horizon'; '- make sure to have a proper ''ground'' with the lower lines'};
            app.Label_3 = uilabel(app.GridLayout18);
            app.Label_3.Text = {'IMPORTANT FOR MacOS:'; '- When starting the simulation for the first time, a window will open and ask you to enable MATLAB in the settings. Please allow this change so that the controls in the simulation work.'; '- If you already have denied this request, this is how to change it manually:'; 'Go to Settings > Privacy & Security > Accessibility. Here, enable MATLAB to control your Computer.'; ''};
            app.Panel_3 = uipanel(app.GridLayout6);
            app.Panel_3.ButtonDownFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.PreviousButton = uibutton(app.GridLayout7, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {'Previous Tip'};
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout7, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Next Tip'};
            app.NextButton.Text = 'Next';
            app.CONTROLPanel = uipanel(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.CONTROLPanel.Tooltip = {'Miscellaneous options.'};
            app.CONTROLPanel.Title = 'CONTROL';
            app.GridLayout2 = uigridlayout(app.CONTROLPanel);
            app.SelectImageButton = uibutton(app.GridLayout2, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Icon = fullfile(pathToMLAPP, 'Help', 'matlab_openfolder_icon.svg');
            app.SelectImageButton.Tooltip = {'Choose a new picture for your tour.'};
            app.SelectImageButton.Text = 'Select Image';
            app.RUNButton = uibutton(app.GridLayout2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Tooltip = {'Run the simulation. Make sure the Planar Area and the Vanishing Point are specified.'};
            app.RUNButton.Text = 'RUN';
            app.REQUIREDPanel = uipanel(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.REQUIREDPanel.Tooltip = {'These features are required to launch the simulation.'};
            app.REQUIREDPanel.Title = 'REQUIRED';
            app.GridLayout = uigridlayout(app.REQUIREDPanel);
            app.DrawPlanarAreaButton = uibutton(app.GridLayout, 'push');
            app.DrawPlanarAreaButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleButtonPushed, true);
            app.DrawPlanarAreaButton.Tag = 'Rectangletag';
            app.DrawPlanarAreaButton.Tooltip = {'To draw the selected Planar Area Shape.'; 'Draw: Draw a rectangle by click&drag.'; 'Reset: Reset the current shape to draw it anew.'};
            app.DrawPlanarAreaButton.Text = {'Draw'; 'Planar Area'};
            app.DrawVanishingPointButton = uibutton(app.GridLayout, 'push');
            app.DrawVanishingPointButton.ButtonPushedFcn = createCallbackFcn(app, @VanishingPointButtonPushed, true);
            app.DrawVanishingPointButton.Tag = 'FPtag';
            app.DrawVanishingPointButton.Tooltip = {'Draw: Draw the vanishing point by clicking the desired place in image.'; 'Reset: Clear the current vanishing point.'};
            app.DrawVanishingPointButton.Text = {'Draw '; 'Vanishing Point'};
            app.EXPERTMODEPanel = uipanel(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.EXPERTMODEPanel.Tooltip = {'These features are optional and not required to launch the simulation.'};
            app.EXPERTMODEPanel.Title = 'EXPERT MODE';
            app.GridLayout3 = uigridlayout(app.EXPERTMODEPanel);
            app.ForegroundObjectsButtonGroup = uibuttongroup(app.GridLayout3);
            app.ForegroundObjectsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ForegroundObjectButtonPushed, true);
            app.ForegroundObjectsButtonGroup.Tooltip = {'Contour foreground objects to remove them from the background image and place them as 2D-shapes into the scene.'};
            app.ForegroundObjectsButtonGroup.Title = 'Foreground Objects';
            app.DrawButton = uitogglebutton(app.ForegroundObjectsButtonGroup);
            app.DrawButton.Tooltip = {'Contour a new foreground object. Please finish drawing the objects by connecting the last dot with the first dot.'};
            app.DrawButton.Text = 'Draw';
            app.ResetcurrentButton = uitogglebutton(app.ForegroundObjectsButtonGroup);
            app.ResetcurrentButton.Tooltip = {'Reset the most recently drawn foreground object.'};
            app.ResetcurrentButton.Text = 'Reset current';
            app.ResetallButton = uitogglebutton(app.ForegroundObjectsButtonGroup);
            app.ResetallButton.Tooltip = {'Reset all foreground objects.'};
            app.ResetallButton.Text = 'Reset all';
            app.NoneButton = uitogglebutton(app.ForegroundObjectsButtonGroup);
            app.NoneButton.Enable = 'off';
            app.NoneButton.Visible = 'off';
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.ResolutionPanel = uipanel(app.GridLayout3);
            app.ResolutionPanel.Tooltip = {'Depending on the performance, you might want to adapt the resolution of the 3D scene here.'};
            app.ResolutionPanel.Title = 'Resolution';
            app.GridLayout4 = uigridlayout(app.ResolutionPanel);
            app.Slider = uislider(app.GridLayout4);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [1 20 40 60 80 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ResolutionValueChanged, true);
            app.Slider.MinorTicks = [1 10 30 50 90 100];
            app.Slider.Tooltip = {'Change the resolution of the 3D scene.'; 'Reduce if rendering takes too long.'};
            app.Slider.Value = 50;
            app.PlanarAreaShapesButtonGroup = uibuttongroup(app.GridLayout3);
            app.PlanarAreaShapesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlanarAreaShapesButtonGroupSelectionChanged, true);
            app.PlanarAreaShapesButtonGroup.Tooltip = {'Select a figure style to draw planar area in that figure.'};
            app.PlanarAreaShapesButtonGroup.Title = 'Planar Area Shapes';
            app.PlanarAreaShapesButtonGroup.ButtonDownFcn = createCallbackFcn(app, @DrawRectPolySelectionChanged, true);
            app.RectangleButton = uiradiobutton(app.PlanarAreaShapesButtonGroup);
            app.RectangleButton.Tag = 'Rectchosen';
            app.RectangleButton.Tooltip = {'Use a Rectangle shape.'};
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.QuadrangleButton = uiradiobutton(app.PlanarAreaShapesButtonGroup);
            app.QuadrangleButton.Tooltip = {'Use a quadrangle shape. Adjust the corners to your liking.'};
            app.QuadrangleButton.Text = 'Quadrangle';
            app.Switch = uiswitch(app.EXPERTMODEPanel, 'rocker');
            app.Switch.Items = {'  ', ' '};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchChangedFcn, true);
            app.Switch.Tooltip = {'Show/Hide Optional Settings.'};
            app.Switch.Value = ' ';
            app.TOURINTOTHEPICTURELabel = uilabel(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.TOURINTOTHEPICTURELabel.Tooltip = {'Welcome to our Computer Vision Project in SS24!'};
            app.TOURINTOTHEPICTURELabel.Text = '  TOUR INTO THE PICTURE ';
            app.Panel_2 = uipanel(app.TourIntoThePictureComputerVisionSS24Group32UIFigure);
            app.Panel_2.Tooltip = {'Click on the switch to show advanced settings.'};
            % Show the figure after all components are created
            app.TourIntoThePictureComputerVisionSS24Group32UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TourIntoThePictureComputerVisionSS24Group32UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TourIntoThePictureComputerVisionSS24Group32UIFigure)
        end
    end
end